classdef Colored_Images_Compression_Using_Neural_Networks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ColoredImageCompressionUsingNeuralNetworksUIFigure  matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        TrainTab                   matlab.ui.container.Tab
        trainingsampleButtonGroup  matlab.ui.container.ButtonGroup
        ImportimageButton          matlab.ui.control.Button
        NameEditFieldLabel         matlab.ui.control.Label
        NameEditField              matlab.ui.control.EditField
        WidthEditFieldLabel        matlab.ui.control.Label
        WidthEditField             matlab.ui.control.EditField
        SizeEditFieldLabel         matlab.ui.control.Label
        SizeEditField              matlab.ui.control.EditField
        HeightEditFieldLabel       matlab.ui.control.Label
        HeightEditField            matlab.ui.control.EditField
        KhonenlayertrainingparametersButtonGroup  matlab.ui.container.ButtonGroup
        neuronsKnobLabel           matlab.ui.control.Label
        neuronsKnob                matlab.ui.control.DiscreteKnob
        LearnRateSlider            matlab.ui.control.Slider
        LearnRateSliderLabel       matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        LearnrateEditField         matlab.ui.control.NumericEditField
        TrainnetworkButton         matlab.ui.control.Button
        ShowmatlabbuiltinnetworktrainingpogresswindowCheckBox  matlab.ui.control.CheckBox
        TrainingprogressPanel      matlab.ui.container.Panel
        Label_2                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        N1LampLabel                matlab.ui.control.Label
        N1Lamp                     matlab.ui.control.Lamp
        N2LampLabel                matlab.ui.control.Label
        N2Lamp                     matlab.ui.control.Lamp
        N3LampLabel                matlab.ui.control.Label
        N3Lamp                     matlab.ui.control.Lamp
        N4LampLabel                matlab.ui.control.Label
        N4Lamp                     matlab.ui.control.Lamp
        N128Lamp_2Label            matlab.ui.control.Label
        N128Lamp_2                 matlab.ui.control.Lamp
        X1LampLabel                matlab.ui.control.Label
        X1Lamp                     matlab.ui.control.Lamp
        X2LampLabel                matlab.ui.control.Label
        X2Lamp                     matlab.ui.control.Lamp
        X3LampLabel                matlab.ui.control.Label
        X3Lamp                     matlab.ui.control.Lamp
        ProgressGaugeLabel         matlab.ui.control.Label
        ProgressGauge              matlab.ui.control.Gauge
        KohonenNetworktrainingsamplePanel  matlab.ui.container.Panel
        UIAxes                     matlab.ui.control.UIAxes
        CompressTab                matlab.ui.container.Tab
        SelectimagetocompressButtonGroup  matlab.ui.container.ButtonGroup
        ImportimageButton_2        matlab.ui.control.Button
        NameEditFieldLabel_2       matlab.ui.control.Label
        NameEditField_2            matlab.ui.control.EditField
        WidthEditFieldLabel_2      matlab.ui.control.Label
        WidthEditField_2           matlab.ui.control.EditField
        SizeEditFieldLabel_2       matlab.ui.control.Label
        SizeEditField_2            matlab.ui.control.EditField
        HeightEditFieldLabel_2     matlab.ui.control.Label
        HeightEditField_2          matlab.ui.control.EditField
        GrossberglayertrainingparametersButtonGroup  matlab.ui.container.ButtonGroup
        LearnRateSlider_2          matlab.ui.control.Slider
        LearnRateSliderLabel_2     matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        LearnrateEditField_2       matlab.ui.control.NumericEditField
        CompressImageButton        matlab.ui.control.Button
        IterationsKnobLabel        matlab.ui.control.Label
        IterationsKnob             matlab.ui.control.Knob
        EditField                  matlab.ui.control.NumericEditField
        compressingprogressPanel   matlab.ui.container.Panel
        ProgressGauge_2Label       matlab.ui.control.Label
        ProgressGauge_2            matlab.ui.control.Gauge
        X1Lamp_2Label              matlab.ui.control.Label
        X1Lamp_2                   matlab.ui.control.Lamp
        X2Lamp_2Label              matlab.ui.control.Label
        X2Lamp_2                   matlab.ui.control.Lamp
        X3Lamp_2Label              matlab.ui.control.Label
        X3Lamp_2                   matlab.ui.control.Lamp
        Label_6                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        N1Lamp_2Label              matlab.ui.control.Label
        N1Lamp_2                   matlab.ui.control.Lamp
        N2Lamp_2Label              matlab.ui.control.Label
        N2Lamp_2                   matlab.ui.control.Lamp
        N3Lamp_2Label              matlab.ui.control.Label
        N3Lamp_2                   matlab.ui.control.Lamp
        N4Lamp_2Label              matlab.ui.control.Label
        N4Lamp_2                   matlab.ui.control.Lamp
        N128LampLabel              matlab.ui.control.Label
        N128Lamp                   matlab.ui.control.Lamp
        Y1LampLabel                matlab.ui.control.Label
        Y1Lamp                     matlab.ui.control.Lamp
        Y2LampLabel                matlab.ui.control.Label
        Y2Lamp                     matlab.ui.control.Lamp
        Y3LampLabel                matlab.ui.control.Label
        Y3Lamp                     matlab.ui.control.Lamp
        SelectedImagePanel         matlab.ui.container.Panel
        UIAxes2                    matlab.ui.control.UIAxes
        DecompressTab              matlab.ui.container.Tab
        SelectfiletodecompressButtonGroup  matlab.ui.container.ButtonGroup
        loadcompressedfileButton   matlab.ui.control.Button
        NameEditFieldLabel_3       matlab.ui.control.Label
        NameEditField_3            matlab.ui.control.EditField
        SizeEditFieldLabel_3       matlab.ui.control.Label
        SizeEditField_3            matlab.ui.control.EditField
        DecompressfileButton       matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        DecompressedImagePanel     matlab.ui.container.Panel
        UIAxes4                    matlab.ui.control.UIAxes
        OriginalImagePanel         matlab.ui.container.Panel
        UIAxes3                    matlab.ui.control.UIAxes
        ComparisionandstatisticsButtonGroup  matlab.ui.container.ButtonGroup
        LoadoriginalimageButton    matlab.ui.control.Button
        NameEditFieldLabel_6       matlab.ui.control.Label
        NameEditField_6            matlab.ui.control.EditField
        SizeEditFieldLabel_6       matlab.ui.control.Label
        SizeEditField_6            matlab.ui.control.EditField
        RatioButton                matlab.ui.control.Button
        PSNRButton                 matlab.ui.control.Button
        Label_9                    matlab.ui.control.Label
        EditField_7                matlab.ui.control.EditField
        Label_10                   matlab.ui.control.Label
        EditField_8                matlab.ui.control.EditField
        SVULabel                   matlab.ui.control.Label
    end

    properties (Access = public)
        %definetion of global variables
        train_image; 
        trained_kohonen_net;
        neurons;
        compress_image;
        clusters_colors;
        clusters_array;
        original_size;
        compress_size;
        original_image;
        decompress_image;
        
    end

    methods (Access = public)
        
      %function prepare_input takes input image and return it as a color vectors array
        function vectors_array= prepare_input(app,input_image)
                 %getting red , green , blue color values arrays
                 red_channel=input_image(:,:,1);
                 green_channel=input_image(:,:,2);
                 blue_channel=input_image(:,:,3);
                 %reshape color arrays to 1 row arrays
                 red_vector=reshape(red_channel,1,[]);
                 green_vector=reshape(green_channel,1,[]);
                 blue_vector=reshape(blue_channel,1,[]);
                 %combine the 3 "row arrays" to create the colors vectors array
                 rgb_vectors=[red_vector; green_vector ; blue_vector];
                 %convert the colors vectors to double and return it 
                 vectors_array=double(rgb_vectors);
        end 
        
        %function kohonen_layer creates our neural network and train it
        function trained_kohonen=kohonen_layer(app,vectors_array...
                 ,neurons_numb,initial_weights,learn_rate,nn)
                 neuronsf=neurons_numb;
                 %create a competitive network with desirable neurons and learn rate
                 net = competlayer(neuronsf,learn_rate);
                 %configure network so we can change the initial weights and parameters
                 net=configure(net,vectors_array);
                 %set the desirable weights vlaue
                 IW = ones(neuronsf,3)*initial_weights;
                 net.IW{1,1}=IW;
                 %set the network train function and adapt function
                 net.trainFcn = 'trainru';
                 net.adaptFcn = 'trains';
                 %set network layer weights learn and weight Function to learnk
                  for i=1:net.numLayers
                     for j=1:net.numInputs
                         net.layerweights{i,j}.weightFcn='learnk';
                         net.layerweights{i,j}.learnFcn='learnk';
                         net.inputweights{i,j}.learnFcn='learnk';
                     end
                  end
                %set training times
                net.trainParam.epochs=1;
                %just for view or hide the training pop out window
                if nn==0
                net.trainParam.showWindow = 0;
                end
                %train the network and return it 
                net = train(net,vectors_array);
                trained_kohonen=adapt(net,vectors_array);
        end
        
        
        %function grossberg_layer trains grossberg Wights for input image
        function trained_grossberg = grossberg_layer(app,input_image,kohonen_out,neurons_numb,...
                 initial_weights,learn_rate,epoches_numb)
        %set initial grossberg weights , learning rate and number of itiration
        weights=  ones(3,neurons_numb) * initial_weights;
        plus=learn_rate;
        %train grossberg weights
        for l=1 : epoches_numb
         for i=1 : neurons_numb
            for j= 1 :length(input_image)
                if kohonen_out(i,j)==1      
                    weights(1,i)=weights(1,i)+(learn_rate*(input_image(1,j)-weights(1,i)));
                    weights(2,i)=weights(2,i)+(learn_rate*(input_image(2,j)-weights(2,i)));
                    weights(3,i)=weights(3,i)+(learn_rate*(input_image(3,j)-weights(3,i)));
                end
            end
         end
        learn_rate=learn_rate+plus;
        end
        % return the trained grossberg weights
       trained_grossberg=weights;
        end


        %function decompress_image decompress the compressed file to an image
        function decompressed_image=decompress(app,Clusters_array,...
                 trained_grossberg,dimonesions)
            %get width , height from compressed file and calculate image size
            width = dimonesions(1,1);
            height= dimonesions(1,2);
            size=width*height;
            %get colors values red , green , blue for each pixel
            po = zeros(3,size);
            for i= 1 : size
                po(1,i)=trained_grossberg(1,Clusters_array(1,i));
                po(2,i)=trained_grossberg(2,Clusters_array(1,i));
                po(3,i)=trained_grossberg(3,Clusters_array(1,i));
            end
            %convert to the original image array format
            rout=zeros(1,size);
            gout=zeros(1,size);
            bout=zeros(1,size);
            for i= 1 : size
                rout(1,i)=po(1,i) ;
                gout(1,i)=po(2,i) ;
                bout(1,i)=po(3,i) ;
            end
            rmat=vec2mat(rout,height)';
            gmat=vec2mat(gout,height)';
            bmat=vec2mat(bout,height)';
            rmat=uint8(rmat);
            gmat=uint8(gmat);
            bmat=uint8(bmat);
            %combine the r , g, b arrays to create the rgb image
            decompressed_image=cat(3,rmat,gmat,bmat);
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %reset all axes to default state 
           
            cla(app.UIAxes,'reset');
            cla(app.UIAxes2,'reset');
            cla(app.UIAxes3,'reset');
            cla(app.UIAxes4,'reset');
            app.UIAxes.Visible='off';
            app.UIAxes2.Visible='off';
            app.UIAxes3.Visible='off';
            app.UIAxes4.Visible='off';
        end

        % Button pushed function: ImportimageButton
        function ImportimageButtonPushed(app, event)
            %select the training image
            [Filename, Pathname] = uigetfile({'*.bmp'},'Select Picture');
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'of';
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'on';
            cd(Pathname);
            A=imread(Filename);
            %assign the image to the global variable train_image so we can call it when needed
            app.train_image=A;
            %show the training image and its info
            imshow(A,'Parent',app.UIAxes);
            InfoImage = imfinfo(Filename)     
            
            Width = InfoImage.Width;
            Height = InfoImage.Height;
            Size = InfoImage.FileSize/1024;

            app.NameEditField.Value     = Filename;
            app.WidthEditField.Value    = num2str(Width) ;
            app.HeightEditField.Value   = num2str(Height);
            app.SizeEditField.Value     = num2str(Size) + " KB";
            % enable train button so we can click it
            app.TrainnetworkButton.Enable = 'on';
        end

        % Button pushed function: TrainnetworkButton
        function TrainnetworkButtonPushed(app, event)
            %just for view
            app.ProgressGaugeLabel.Text='Training network';
            nn=0;
            if app.ShowmatlabbuiltinnetworktrainingpogresswindowCheckBox.Value==true
                nn=1;
            end
            %prepare the training image using pepare_input function   
            vectorized_train_image=prepare_input(app,app.train_image);
            % "just for view" activate the first animation of the progress gauge 
            app.ProgressGauge.ScaleColors = [0 1 0];
            app.ProgressGauge.ScaleColorLimits = [0 10];
            app.ProgressGauge.Value=10;
            %"just for view" activate network input neurons lamps
            app.X1Lamp.Color=[0 1 0];
            app.X2Lamp.Color=[0 1 0];
            app.X3Lamp.Color=[0 1 0];
            %read the "user desirable" neurons number, learn rate 
            app.neurons=str2double(app.neuronsKnob.Value);
            learn_rate=app.LearnrateEditField.Value;
            %train kohonen network with the selected images and parameters
            app.trained_kohonen_net=kohonen_layer(app,vectorized_train_image,...
                app.neurons,1/sqrt(3),learn_rate,nn);
            % "just for view" activate the second animation of the progress gauge
            app.ProgressGauge.ScaleColorLimits = [0 100];
            app.ProgressGauge.Value=100;
            % "just for view" activate network kohonen neurons lamps
            app.N1Lamp.Color=[0 1 0];
            app.N2Lamp.Color=[0 1 0];
            app.N3Lamp.Color=[0 1 0];
            app.N4Lamp.Color=[0 1 0];
            app.N128Lamp_2.Color=[0 1 0];
            app.ProgressGaugeLabel.Text='Training succeed';
            app.ImportimageButton_2.Enable='on';
        end

        % Value changed function: LearnrateEditField
        function LearnrateEditFieldValueChanged(app, event)
            value = app.LearnrateEditField.Value;
            app.LearnRateSlider.Value=value;
        end

        % Value changed function: LearnRateSlider
        function LearnRateSliderValueChanged(app, event)
            value = app.LearnRateSlider.Value;
            app.LearnrateEditField.Value=value;
        end

        % Value changed function: neuronsKnob
        function neuronsKnobValueChanged(app, event)
           app.N128Lamp_2Label.Text="N"+app.neuronsKnob.Value;
           app.N128LampLabel.Text="N"+app.neuronsKnob.Value;
            
        end

        % Button pushed function: ImportimageButton_2
        function ImportimageButton_2Pushed(app, event)
            %select image to compress then show it with its info
            [Filename, Pathname] = uigetfile({'*.bmp'},'Select Picture');
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'of';
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'on';
            cd(Pathname);
            A=imread(Filename);
            imshow(A,'Parent',app.UIAxes2);
            app.compress_image = A;
            InfoImage = imfinfo(Filename);
            
            Width = InfoImage.Width;
            Height = InfoImage.Height;
            Size = InfoImage.FileSize/1024;

            app.NameEditField_2.Value     = Filename;
            app.WidthEditField_2.Value    = num2str(Width);
            app.HeightEditField_2.Value   = num2str(Height);
            app.SizeEditField_2.Value     = num2str(Size) + " KB";
            app.CompressImageButton.Enable='on';
        end

        % Value changed function: IterationsKnob
        function IterationsKnobValueChanged(app, event)
            value = app.IterationsKnob.Value;
            app.EditField.Value=value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            app.IterationsKnob.Value=value;
        end

        % Value changed function: LearnrateEditField_2
        function LearnrateEditField_2ValueChanged(app, event)
            value = app.LearnrateEditField_2.Value;
            app.LearnRateSlider_2.Value=value;
        end

        % Value changed function: LearnRateSlider_2
        function LearnRateSlider_2ValueChanged(app, event)
            value = app.LearnRateSlider_2.Value;
            app.LearnrateEditField_2.Value=value;
        end

        % Button pushed function: CompressImageButton
        function CompressImageButtonPushed(app, event)

            app.ProgressGauge_2Label.Text='Compressing Image';
            %prepare the image using prepare_input function
            vectorized_input_image=prepare_input(app,app.compress_image);
            % "just for view" activate the first animation of the progress gauge
            app.ProgressGauge_2.ScaleColors = [0 1 0];
            app.ProgressGauge_2.ScaleColorLimits = [0 30];
            app.ProgressGauge_2.Value=30;
            % "just for view" activate network input neurons lamps
            app.X1Lamp_2.Color=[0 1 0];
            app.X2Lamp_2.Color=[0 1 0];
            app.X3Lamp_2.Color=[0 1 0];
            % call kohonen network to cluster the vectorized image
            kohonen_out=app.trained_kohonen_net(vectorized_input_image);
            % assign clusters numbers
            app.clusters_array=vec2ind(kohonen_out);
            % "just for view" activate the second animation of the progress gauge
            app.ProgressGauge_2.ScaleColorLimits = [0 60];
            app.ProgressGauge_2.Value=60;
            % "just for view" activate network kohonen neurons lamps
            app.N1Lamp_2.Color=[0 1 0];
            app.N2Lamp_2.Color=[0 1 0];
            app.N3Lamp_2.Color=[0 1 0];
            app.N4Lamp_2.Color=[0 1 0];
            app.N128Lamp.Color=[0 1 0];
            % calculate grossberg weights
            learn_rate=app.LearnrateEditField_2.Value;
            iterations=int8(app.IterationsKnob.Value);
            app.clusters_colors=grossberg_layer(app,vectorized_input_image,kohonen_out,app.neurons,...
                1/sqrt(3),learn_rate,iterations);
            % "just for view" activate the second animation of the progress gauge
            app.ProgressGauge_2.ScaleColorLimits = [0 100];
            app.ProgressGauge_2.Value=100;
            % "just for view" activate network grossberg neurons lamps
            app.Y1Lamp.Color=[0 1 0];
            app.Y2Lamp.Color=[0 1 0];
            app.Y3Lamp.Color=[0 1 0];
            app.ProgressGauge_2Label.Text='compressing succeed';
            % create and save the compressed mat file
            nam=app.NameEditField_2.Value;
            dimonesions=[str2num(app.WidthEditField_2.Value), str2num(app.HeightEditField_2.Value)];
            clust=app.clusters_array;
            colors=app.clusters_colors;
            Pathname=uigetdir('select output folder');
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'of';
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'on';
            cd(Pathname);
            [Fpath , Fname , Fext]=fileparts(nam);
            comp='_compressed_file';
            Fname=strcat(Fname,comp);
            save (Fname,  'clust' ,'colors' ,'dimonesions')
        end

        % Button pushed function: loadcompressedfileButton
        function loadcompressedfileButtonPushed(app, event)
            %select the compressed mat file that we want to decompress
            [Filename, Pathname] = uigetfile({'*.mat'},'Select compressed file');
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'of';
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'on';
            cd(Pathname);
            A=Filename;
            s=dir(Filename);
            Size = s.bytes/1024;
            app.compress_size=Size;
            app.NameEditField_3.Value=A;
            app.SizeEditField_3.Value=num2str(Size)+" KB";
            app.DecompressfileButton.Enable='on';
        end

        % Button pushed function: DecompressfileButton
        function DecompressfileButtonPushed(app, event)
            %decompress the compressed file and show the decompressed image
            Fname=app.NameEditField_3.Value;
            load (Fname,  'clust' ,'colors' ,'dimonesions')
            image=decompress(app,clust,colors,dimonesions);
            app.decompress_image=image;
            imshow(image,'Parent',app.UIAxes4);
            app.RatioButton.Enable='on';
            app.PSNRButton.Enable='on';
            app.SaveButton.Enable='on';
            app.NameEditField_6.Value="";
            app.SizeEditField_6.Value="";
            app.EditField_7.Value="";
            app.EditField_8.Value="";
        end

        % Button pushed function: LoadoriginalimageButton
        function LoadoriginalimageButtonPushed(app, event)
            %select the orginal image and show it with its info
            [Filename, Pathname] = uigetfile({'*.bmp'},'Select original picture');
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'of';
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'on';
            cd(Pathname);
            A=Filename;
            s=dir(Filename);
            im=imread(A);
            app.original_image=im;
            imshow(A,'Parent',app.UIAxes3);
            Size = s.bytes/1024;
            app.original_size=Size;
            app.NameEditField_6.Value=A;
            app.SizeEditField_6.Value=num2str(Size)+" KB";
        end

        % Button pushed function: RatioButton
        function RatioButtonPushed(app, event)
            % calculate compression ratio
            original=app.original_size;
            compressed=app.compress_size;
            ratio=original/compressed;
            app.EditField_7.Value=num2str(ratio);   
            app.RatioButton.Enable='off';
        end

        % Button pushed function: PSNRButton
        function PSNRButtonPushed(app, event)
            %calculate PSNR
            PSNR=psnr(app.decompress_image,app.original_image);
            app.EditField_8.Value=num2str(PSNR);
            app.PSNRButton.Enable='off';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % save the image after the decompression
            nam=app.NameEditField_3.Value;
            Pathname=uigetdir('select output folder');
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'of';
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Visible = 'on';
            cd(Pathname);
            ext='_decompressed.bmp';
            [Fpath , Fname , Fext]=fileparts(nam);
            Fname=strcat(Fname,ext);
            imwrite (app.decompress_image,Fname,'bmp');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ColoredImageCompressionUsingNeuralNetworksUIFigure = uifigure;
            app.ColoredImageCompressionUsingNeuralNetworksUIFigure.Name = 'Colored Image Compression Using Neural Networks';
            app.TabGroup = uitabgroup(app.ColoredImageCompressionUsingNeuralNetworksUIFigure);
            app.TrainTab = uitab(app.TabGroup);
            app.TrainTab.Title = 'Train';
            app.trainingsampleButtonGroup = uibuttongroup(app.TrainTab);
            app.trainingsampleButtonGroup.Title = 'training sample';
            app.ImportimageButton = uibutton(app.trainingsampleButtonGroup, 'push');
            app.ImportimageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportimageButtonPushed, true);
            app.ImportimageButton.Text = 'Import image';
            app.NameEditFieldLabel = uilabel(app.trainingsampleButtonGroup);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.trainingsampleButtonGroup, 'text');
            app.NameEditField.Editable = 'off';
            app.WidthEditFieldLabel = uilabel(app.trainingsampleButtonGroup);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.trainingsampleButtonGroup, 'text');
            app.WidthEditField.Editable = 'off';
            app.SizeEditFieldLabel = uilabel(app.trainingsampleButtonGroup);
            app.SizeEditFieldLabel.Text = 'Size';
            app.SizeEditField = uieditfield(app.trainingsampleButtonGroup, 'text');
            app.SizeEditField.Editable = 'off';
            app.HeightEditFieldLabel = uilabel(app.trainingsampleButtonGroup);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.trainingsampleButtonGroup, 'text');
            app.HeightEditField.Editable = 'off';
            app.KhonenlayertrainingparametersButtonGroup = uibuttongroup(app.TrainTab);
            app.KhonenlayertrainingparametersButtonGroup.Title = 'Khonen layer training parameters';
            app.neuronsKnobLabel = uilabel(app.KhonenlayertrainingparametersButtonGroup);
            app.neuronsKnobLabel.Text = 'neurons';
            app.neuronsKnob = uiknob(app.KhonenlayertrainingparametersButtonGroup, 'discrete');
            app.neuronsKnob.Items = {'8', '16', '32', '64', '128'};
            app.neuronsKnob.ValueChangedFcn = createCallbackFcn(app, @neuronsKnobValueChanged, true);
            app.neuronsKnob.Value = '128';
            app.LearnRateSlider = uislider(app.KhonenlayertrainingparametersButtonGroup);
            app.LearnRateSlider.Limits = [0 1];
            app.LearnRateSlider.MajorTicks = [];
            app.LearnRateSlider.MajorTickLabels = {};
            app.LearnRateSlider.ValueChangedFcn = createCallbackFcn(app, @LearnRateSliderValueChanged, true);
            app.LearnRateSlider.MinorTicks = [];
            app.LearnRateSlider.Value = 0.19;
            app.LearnRateSliderLabel = uilabel(app.KhonenlayertrainingparametersButtonGroup);
            app.LearnRateSliderLabel.Text = 'Learn Rate';
            app.Label = uilabel(app.KhonenlayertrainingparametersButtonGroup);
            app.Label.Text = '';
            app.LearnrateEditField = uieditfield(app.KhonenlayertrainingparametersButtonGroup, 'numeric');
            app.LearnrateEditField.Limits = [0 1];
            app.LearnrateEditField.ValueChangedFcn = createCallbackFcn(app, @LearnrateEditFieldValueChanged, true);
            app.LearnrateEditField.Value = 0.7;
            app.TrainnetworkButton = uibutton(app.KhonenlayertrainingparametersButtonGroup, 'push');
            app.TrainnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @TrainnetworkButtonPushed, true);
            app.TrainnetworkButton.Enable = 'off';
            app.TrainnetworkButton.Text = 'Train network';
            app.ShowmatlabbuiltinnetworktrainingpogresswindowCheckBox = uicheckbox(app.KhonenlayertrainingparametersButtonGroup);
            app.ShowmatlabbuiltinnetworktrainingpogresswindowCheckBox.Text = 'Show matlab builtin network training pogress window ';
            app.TrainingprogressPanel = uipanel(app.TrainTab);
            app.TrainingprogressPanel.Title = 'Training progress';
            app.Label_2 = uilabel(app.TrainingprogressPanel);
            app.Label_2.Text = '.';
            app.Label_3 = uilabel(app.TrainingprogressPanel);
            app.Label_3.Text = '.';
            app.Label_4 = uilabel(app.TrainingprogressPanel);
            app.Label_4.Text = '.';
            app.N1LampLabel = uilabel(app.TrainingprogressPanel);
            app.N1LampLabel.Text = 'N1';
            app.N1Lamp = uilamp(app.TrainingprogressPanel);
            app.N2LampLabel = uilabel(app.TrainingprogressPanel);
            app.N2LampLabel.Text = 'N2';
            app.N2Lamp = uilamp(app.TrainingprogressPanel);
            app.N3LampLabel = uilabel(app.TrainingprogressPanel);
            app.N3LampLabel.Text = 'N3';
            app.N3Lamp = uilamp(app.TrainingprogressPanel);
            app.N4LampLabel = uilabel(app.TrainingprogressPanel);
            app.N4LampLabel.Text = 'N4';
            app.N4Lamp = uilamp(app.TrainingprogressPanel);
            app.N128Lamp_2Label = uilabel(app.TrainingprogressPanel);
            app.N128Lamp_2Label.Text = 'N128';
            app.N128Lamp_2 = uilamp(app.TrainingprogressPanel);
            app.X1LampLabel = uilabel(app.TrainingprogressPanel);
            app.X1LampLabel.Text = 'X1';
            app.X1Lamp = uilamp(app.TrainingprogressPanel);
            app.X2LampLabel = uilabel(app.TrainingprogressPanel);
            app.X2LampLabel.Text = 'X2';
            app.X2Lamp = uilamp(app.TrainingprogressPanel);
            app.X3LampLabel = uilabel(app.TrainingprogressPanel);
            app.X3LampLabel.Text = 'X3';
            app.X3Lamp = uilamp(app.TrainingprogressPanel);
            app.ProgressGaugeLabel = uilabel(app.TrainingprogressPanel);
            app.ProgressGaugeLabel.Text = 'Progress Gauge';
            app.ProgressGauge = uigauge(app.TrainingprogressPanel, 'circular');
            app.ProgressGauge.MajorTicks = [0 100];
            app.KohonenNetworktrainingsamplePanel = uipanel(app.TrainTab);
            app.KohonenNetworktrainingsamplePanel.Title = 'Kohonen Network training sample';
            app.UIAxes = uiaxes(app.KohonenNetworktrainingsamplePanel);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.CompressTab = uitab(app.TabGroup);
            app.CompressTab.Title = 'Compress';
            app.SelectimagetocompressButtonGroup = uibuttongroup(app.CompressTab);
            app.SelectimagetocompressButtonGroup.Title = 'Select image to compress';
            app.ImportimageButton_2 = uibutton(app.SelectimagetocompressButtonGroup, 'push');
            app.ImportimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportimageButton_2Pushed, true);
            app.ImportimageButton_2.Enable = 'off';
            app.ImportimageButton_2.Text = 'Import image';
            app.NameEditFieldLabel_2 = uilabel(app.SelectimagetocompressButtonGroup);
            app.NameEditFieldLabel_2.Text = 'Name';
            app.NameEditField_2 = uieditfield(app.SelectimagetocompressButtonGroup, 'text');
            app.NameEditField_2.Editable = 'off';
            app.WidthEditFieldLabel_2 = uilabel(app.SelectimagetocompressButtonGroup);
            app.WidthEditFieldLabel_2.Text = 'Width';
            app.WidthEditField_2 = uieditfield(app.SelectimagetocompressButtonGroup, 'text');
            app.WidthEditField_2.Editable = 'off';
            app.SizeEditFieldLabel_2 = uilabel(app.SelectimagetocompressButtonGroup);
            app.SizeEditFieldLabel_2.Text = 'Size';
            app.SizeEditField_2 = uieditfield(app.SelectimagetocompressButtonGroup, 'text');
            app.SizeEditField_2.Editable = 'off';
            app.HeightEditFieldLabel_2 = uilabel(app.SelectimagetocompressButtonGroup);
            app.HeightEditFieldLabel_2.Text = 'Height';
            app.HeightEditField_2 = uieditfield(app.SelectimagetocompressButtonGroup, 'text');
            app.HeightEditField_2.Editable = 'off';
            app.GrossberglayertrainingparametersButtonGroup = uibuttongroup(app.CompressTab);
            app.GrossberglayertrainingparametersButtonGroup.Title = 'Grossberg layer training parameters';
            app.LearnRateSlider_2 = uislider(app.GrossberglayertrainingparametersButtonGroup);
            app.LearnRateSlider_2.Limits = [0 1];
            app.LearnRateSlider_2.MajorTicks = [];
            app.LearnRateSlider_2.MajorTickLabels = {};
            app.LearnRateSlider_2.ValueChangedFcn = createCallbackFcn(app, @LearnRateSlider_2ValueChanged, true);
            app.LearnRateSlider_2.MinorTicks = [];
            app.LearnRateSlider_2.Value = 0.19;
            app.LearnRateSliderLabel_2 = uilabel(app.GrossberglayertrainingparametersButtonGroup);
            app.LearnRateSliderLabel_2.Text = 'Learn Rate';
            app.Label_5 = uilabel(app.GrossberglayertrainingparametersButtonGroup);
            app.Label_5.Text = '';
            app.LearnrateEditField_2 = uieditfield(app.GrossberglayertrainingparametersButtonGroup, 'numeric');
            app.LearnrateEditField_2.Limits = [0 1];
            app.LearnrateEditField_2.ValueChangedFcn = createCallbackFcn(app, @LearnrateEditField_2ValueChanged, true);
            app.LearnrateEditField_2.Value = 0.001;
            app.CompressImageButton = uibutton(app.GrossberglayertrainingparametersButtonGroup, 'push');
            app.CompressImageButton.ButtonPushedFcn = createCallbackFcn(app, @CompressImageButtonPushed, true);
            app.CompressImageButton.Enable = 'off';
            app.CompressImageButton.Text = 'Compress Image';
            app.IterationsKnobLabel = uilabel(app.GrossberglayertrainingparametersButtonGroup);
            app.IterationsKnobLabel.Text = 'Iterations';
            app.IterationsKnob = uiknob(app.GrossberglayertrainingparametersButtonGroup, 'continuous');
            app.IterationsKnob.Limits = [0 50];
            app.IterationsKnob.ValueChangedFcn = createCallbackFcn(app, @IterationsKnobValueChanged, true);
            app.IterationsKnob.Value = 12;
            app.EditField = uieditfield(app.GrossberglayertrainingparametersButtonGroup, 'numeric');
            app.EditField.Limits = [1 50];
            app.EditField.ValueDisplayFormat = '%.0f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 12;
            app.compressingprogressPanel = uipanel(app.CompressTab);
            app.compressingprogressPanel.Title = 'compressing progress';
            app.ProgressGauge_2Label = uilabel(app.compressingprogressPanel);
            app.ProgressGauge_2Label.Text = 'Progress Gauge';
            app.ProgressGauge_2 = uigauge(app.compressingprogressPanel, 'circular');
            app.ProgressGauge_2.MajorTicks = [0 100];
            app.X1Lamp_2Label = uilabel(app.compressingprogressPanel);
            app.X1Lamp_2Label.Text = 'X1';
            app.X1Lamp_2 = uilamp(app.compressingprogressPanel);
            app.X2Lamp_2Label = uilabel(app.compressingprogressPanel);
            app.X2Lamp_2Label.Text = 'X2';
            app.X2Lamp_2 = uilamp(app.compressingprogressPanel);
            app.X3Lamp_2Label = uilabel(app.compressingprogressPanel);
            app.X3Lamp_2Label.Text = 'X3';
            app.X3Lamp_2 = uilamp(app.compressingprogressPanel);
            app.Label_6 = uilabel(app.compressingprogressPanel);
            app.Label_6.Text = '.';
            app.Label_7 = uilabel(app.compressingprogressPanel);
            app.Label_7.Text = '.';
            app.Label_8 = uilabel(app.compressingprogressPanel);
            app.Label_8.Text = '.';
            app.N1Lamp_2Label = uilabel(app.compressingprogressPanel);
            app.N1Lamp_2Label.Text = 'N1';
            app.N1Lamp_2 = uilamp(app.compressingprogressPanel);
            app.N2Lamp_2Label = uilabel(app.compressingprogressPanel);
            app.N2Lamp_2Label.Text = 'N2';
            app.N2Lamp_2 = uilamp(app.compressingprogressPanel);
            app.N3Lamp_2Label = uilabel(app.compressingprogressPanel);
            app.N3Lamp_2Label.Text = 'N3';
            app.N3Lamp_2 = uilamp(app.compressingprogressPanel);
            app.N4Lamp_2Label = uilabel(app.compressingprogressPanel);
            app.N4Lamp_2Label.Text = 'N4';
            app.N4Lamp_2 = uilamp(app.compressingprogressPanel);
            app.N128LampLabel = uilabel(app.compressingprogressPanel);
            app.N128LampLabel.Text = 'N128';
            app.N128Lamp = uilamp(app.compressingprogressPanel);
            app.Y1LampLabel = uilabel(app.compressingprogressPanel);
            app.Y1LampLabel.Text = 'Y1';
            app.Y1Lamp = uilamp(app.compressingprogressPanel);
            app.Y2LampLabel = uilabel(app.compressingprogressPanel);
            app.Y2LampLabel.Text = 'Y2';
            app.Y2Lamp = uilamp(app.compressingprogressPanel);
            app.Y3LampLabel = uilabel(app.compressingprogressPanel);
            app.Y3LampLabel.Text = 'Y3';
            app.Y3Lamp = uilamp(app.compressingprogressPanel);
            app.SelectedImagePanel = uipanel(app.CompressTab);
            app.SelectedImagePanel.Title = 'Selected Image';
            app.UIAxes2 = uiaxes(app.SelectedImagePanel);
            app.UIAxes2.XLim = [0 1];
            app.UIAxes2.YLim = [0 1];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes2.CLim = [0 1];
            app.UIAxes2.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.ZTick = [0 0.5 1];
            app.DecompressTab = uitab(app.TabGroup);
            app.DecompressTab.Title = 'Decompress';
            app.SelectfiletodecompressButtonGroup = uibuttongroup(app.DecompressTab);
            app.SelectfiletodecompressButtonGroup.Title = 'Select file to decompress';
            app.loadcompressedfileButton = uibutton(app.SelectfiletodecompressButtonGroup, 'push');
            app.loadcompressedfileButton.ButtonPushedFcn = createCallbackFcn(app, @loadcompressedfileButtonPushed, true);
            app.loadcompressedfileButton.Text = 'load compressed file';
            app.NameEditFieldLabel_3 = uilabel(app.SelectfiletodecompressButtonGroup);
            app.NameEditFieldLabel_3.Text = 'Name';
            app.NameEditField_3 = uieditfield(app.SelectfiletodecompressButtonGroup, 'text');
            app.NameEditField_3.Editable = 'off';
            app.SizeEditFieldLabel_3 = uilabel(app.SelectfiletodecompressButtonGroup);
            app.SizeEditFieldLabel_3.Text = 'Size';
            app.SizeEditField_3 = uieditfield(app.SelectfiletodecompressButtonGroup, 'text');
            app.SizeEditField_3.Editable = 'off';
            app.DecompressfileButton = uibutton(app.SelectfiletodecompressButtonGroup, 'push');
            app.DecompressfileButton.ButtonPushedFcn = createCallbackFcn(app, @DecompressfileButtonPushed, true);
            app.DecompressfileButton.Enable = 'off';
            app.DecompressfileButton.Text = 'Decompress file';
            app.SaveButton = uibutton(app.SelectfiletodecompressButtonGroup, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.DecompressedImagePanel = uipanel(app.DecompressTab);
            app.DecompressedImagePanel.Title = 'Decompressed Image';
            app.UIAxes4 = uiaxes(app.DecompressedImagePanel);
            app.UIAxes4.XLim = [0 1];
            app.UIAxes4.YLim = [0 1];
            app.UIAxes4.ZLim = [0 1];
            app.UIAxes4.CLim = [0 1];
            app.UIAxes4.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes4.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes4.ZTick = [0 0.5 1];
            app.OriginalImagePanel = uipanel(app.DecompressTab);
            app.OriginalImagePanel.Title = 'Original Image';
            app.UIAxes3 = uiaxes(app.OriginalImagePanel);
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3.YLim = [0 1];
            app.UIAxes3.ZLim = [0 1];
            app.UIAxes3.CLim = [0 1];
            app.UIAxes3.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes3.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes3.ZTick = [0 0.5 1];
            app.ComparisionandstatisticsButtonGroup = uibuttongroup(app.DecompressTab);
            app.ComparisionandstatisticsButtonGroup.Title = 'Comparision and statistics';
            app.LoadoriginalimageButton = uibutton(app.ComparisionandstatisticsButtonGroup, 'push');
            app.LoadoriginalimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadoriginalimageButtonPushed, true);
            app.LoadoriginalimageButton.Text = 'Load original image';
            app.NameEditFieldLabel_6 = uilabel(app.ComparisionandstatisticsButtonGroup);
            app.NameEditFieldLabel_6.Text = 'Name';
            app.NameEditField_6 = uieditfield(app.ComparisionandstatisticsButtonGroup, 'text');
            app.NameEditField_6.Editable = 'off';
            app.SizeEditFieldLabel_6 = uilabel(app.ComparisionandstatisticsButtonGroup);
            app.SizeEditFieldLabel_6.Text = 'Size';
            app.SizeEditField_6 = uieditfield(app.ComparisionandstatisticsButtonGroup, 'text');
            app.SizeEditField_6.Editable = 'off';
            app.RatioButton = uibutton(app.ComparisionandstatisticsButtonGroup, 'push');
            app.RatioButton.ButtonPushedFcn = createCallbackFcn(app, @RatioButtonPushed, true);
            app.RatioButton.Enable = 'off';
            app.RatioButton.Text = 'Ratio';
            app.PSNRButton = uibutton(app.ComparisionandstatisticsButtonGroup, 'push');
            app.PSNRButton.ButtonPushedFcn = createCallbackFcn(app, @PSNRButtonPushed, true);
            app.PSNRButton.Enable = 'off';
            app.PSNRButton.Text = 'PSNR';
            app.Label_9 = uilabel(app.ComparisionandstatisticsButtonGroup);
            app.Label_9.Text = '';
            app.EditField_7 = uieditfield(app.ComparisionandstatisticsButtonGroup, 'text');
            app.EditField_7.Editable = 'off';
            app.Label_10 = uilabel(app.ComparisionandstatisticsButtonGroup);
            app.Label_10.Text = '';
            app.EditField_8 = uieditfield(app.ComparisionandstatisticsButtonGroup, 'text');
            app.SVULabel = uilabel(app.ColoredImageCompressionUsingNeuralNetworksUIFigure);
            app.SVULabel.Text = '© 2019 SVU';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Colored_Images_Compression_Using_Neural_Networks

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ColoredImageCompressionUsingNeuralNetworksUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ColoredImageCompressionUsingNeuralNetworksUIFigure)
        end
    end
end